classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Barcode_decoderUIFigure  matlab.ui.Figure
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        input_img % Храним img 
    end

    methods (Access = private)

        function out = detector(app, img)
        %% Locally Adaptive Thresholding
        bw = imbinarize(img ,'adaptive','ForegroundPolarity','dark')==0;
        figure
        subplot(2,2,1)
        imshow(bw);

        %% Filtering By Eccentricity
        subplot(2,2,2)
        prop = regionprops(bw,'Eccentricity');
        ecc = -sort(-cat(1,prop.Eccentricity));
        e_range = min(60,size(ecc,1));
        bw_e = bwpropfilt(bw,'Eccentricity',[ecc(e_range) ecc(1)]);
        imshow(bw_e);

        %% Filtering By Major Axis Length
        subplot(2,2,3)
        prop = regionprops(bw_e,'MajorAxisLength');
        axis_length = cat(1,prop.MajorAxisLength);
        h = histogram(axis_length,50);
        [~,idx] = max(h.BinCounts);
        l_range = 20;                            % Make this dyanaminc
        range = h.BinEdges([max(1,idx-l_range-1),min(idx+l_range+1,size(h.BinEdges,2))]);
        bw_l = bwpropfilt(bw_e,'MajorAxisLength',range);
        imshow(bw_l);

        %% Filtering By Orientation
        subplot(2,2,4)
        prop = regionprops(bw_l,'Orientation');
        axis_length = cat(1,prop.Orientation);
        h = histogram(axis_length,[-90:0,1:90]);
        [~,idx] = max(h.BinCounts);
        o_range = 20;
        range = h.BinEdges([max(1,idx-o_range-1),min(idx+o_range+1,size(h.BinEdges,2))]);
        bw_o = bwpropfilt(bw_l,'Orientation',range);

        if( idx-o_range-1 < 1)
            range = h.BinEdges([mod(idx-o_range-1,size(h.BinEdges,2))+1,size(h.BinEdges,2)]);
            bw_o = logical(bw_o + bwpropfilt(bw_l,'Orientation',range));
        elseif( idx+o_range+1 > size(h.BinEdges,2))
            range = h.BinEdges([1,mod(idx+o_range+1,size(h.BinEdges,2))+1]);
            bw_o = logical(bw_o + bwpropfilt(bw_l,'Orientation',range));
        end
        imshow(bw_o);

        out = 1 - bw_o; %return value
        end


        function out = decode(app, img)
        I = img;
        if(size(img,3) == 3)
            img = rgb2gray(img);
        end
        img = detector(app, img);

        %% Crop the image to desired size
        [~,b] = sort(-sum((1-img')));
        sel = round(mean(b(1:50)));

        %% Take band aroung 'sel' and convert it to scan line
        range = 10;
        band = img(sel-range:sel+range,:);
        inp =round(sum(band)./(2*range));

        %% Finding the run length encoding of the scanned line
        idx = find(diff([inp(1)-1, inp]));
        barCode = [inp(idx); diff([idx,numel(inp)+1])];
        barCode(1,:) = (barCode(1,:) ~= 1); % Fliping 0's and `'s

        %% Find the 1 0 1 pattern in the barcode(Update)
        len = size(barCode,2);
        s_idx = 1;
        for i = 1:len-2
            if isequal(barCode(1,[i,i+1,i+2]),[1,0,1])
                s_idx = i+3;
                break;
            end
        end

        %% Compute the map
        % map => mapping of the digit encoding
        %     => [left_odd,left_even, right,relation_to_digit_1]
        map = [0:9;
            "0001101", "0011001", "0010011", "0111101", "0100011", "0110001", "0101111", "0111011", "0110111", "0001011";
            "0100111", "0110011", "0011011", "0100001", "0011101", "0111001", "0000101", "0010001", "0001001", "0010111";
            "1110010", "1100110", "1101100", "1000010", "1011100" ,"1001110", "1010000", "1000100", "1001000", "1110100";
            "OOOOOOEEEEEE", "OOEOEEEEEEEE", "OOEEOEEEEEEE", "OOEEEOEEEEEE", "OEOOEEEEEEEE", "OEEOOEEEEEEE", "OEEEOOEEEEEE", "OEOEOEEEEEEE", "OEOEEOEEEEEE", "OEEOEOEEEEEE";];
        left = [3 2 1 1; 2 2 2 1; 2 1 2 2; 1 4 1 1;1 1 3 2;1 2 3 1; 1 1 1 4; 1 3 1 2; 1 2 1 3; 3 1 1 2; 1 1 2 3; 1 2 2 2; 2 2 1 2; 1 1 4 1; 2 3 1 1; 1 3 2 1; 4 1 1 1; 2 1 3 1;3 1 2 1; 2 1 1 3];
        left_p = ['O','O','O','O','O','O','O','O','O','O','E','E','E','E','E','E','E','E','E','E'];
        left_m = [0:9, 0:9];
        right = [3 2 1 1; 2 2 2 1; 2 1 2 2; 1 4 1 1;1 1 3 2;1 2 3 1; 1 1 1 4; 1 3 1 2; 1 2 1 3; 3 1 1 2;];
        right_m = 0:9;

        %% Ensure reading from left to right
        first = barCode(:,s_idx:s_idx+6*4-1);
        width = zeros(1,size(first(1,:),2));
        for i = 1:4:size(first(1,:),2)
            tmp = [i,i+1,i+2,i+3];
            x = sum(first(2,tmp))/7;
            width(tmp) = x;
        end
        first(2,:) = first(2,:)./width;
        second = barCode(:,s_idx+6*4+5:s_idx+2*6*4+5-1);
        for i = 1:4:size(first(1,:),2)
            tmp = [i,i+1,i+2,i+3];
            x = sum(second(2,tmp))/7;
            width(tmp) = x;
        end
        second(2,:) = second(2,:)./width;

        str = "";
        str = strcat(str,repmat(int2str(first(1,1)),1,round(first(2,1))));
        str = strcat(str,repmat(int2str(first(1,2)),1,round(first(2,2))));
        str = strcat(str,repmat(int2str(first(1,3)),1,round(first(2,3))));
        str = strcat(str,repmat(int2str(first(1,4)),1,round(first(2,4))));

        % The first digit from the left group is always encoded with odd parity
        if isempty(map(2,ismember(map(2,:),str)))
            temp = first;
            first = second(:,size(second,2):-1:1);
            second = temp(:,size(temp,2):-1:1);
        end

        code = [first,second]; % Bar code in the right order


        out = ""; % Decoded Text
        par = ""; % Holds the parity of each digit

        %% Find the digits using Map
        for i=1:4:size(code,2)
            str = "";
            str = strcat(str,repmat(int2str(code(1,i)),1,round(code(2,i))));
            str = strcat(str,repmat(int2str(code(1,i+1)),1,round(code(2,i+1))));
            str = strcat(str,repmat(int2str(code(1,i+2)),1,round(code(2,i+2))));
            str = strcat(str,repmat(int2str(code(1,i+3)),1,round(code(2,i+3))));


            if ~isempty(map(1,ismember(map(2,:),str)))
                out = strcat(out,map(1,ismember(map(2,:),str)));
                par = strcat(par,"O");
            elseif ~isempty(map(1,ismember(map(3,:),str)))
                out = strcat(out,map(1,ismember(map(3,:),str)));
                par = strcat(par,"E");
            elseif ~isempty(map(1,ismember(map(4,:),str)))
                out = strcat(out,map(1,ismember(map(4,:),str)));
                par = strcat(par,"E");
            else
                if code(1,i) == 0
                    idx = knnsearch(left,code(2,[i,i+1,i+2,i+3]));
                    out = strcat(out,int2str(left_m(idx)));
                    par = strcat(par,left_p(idx));
                else
                    idx = knnsearch(right,code(2,[i,i+1,i+2,i+3]));
                    out = strcat(out,int2str(right_m(idx)));
                    par = strcat(par,'E');
                end
            end
        end

        %% Find the first digit using the Parity
        out = strcat(map(1,ismember(map(5,:),par)),out);

        %% Check Party-bit

        figure
        subplot(1,1,1)
        imshow(I)
        title(out)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            set(app.Button, 'Enable', 'off');
            set(app.Button_2, 'Enable', 'off');
            

            [file,path] = uigetfile('*.png','Выберите изображение');
            full_path = fullfile(path, file);

            app.input_img = imread(full_path);

            set(app.Button, 'Enable', 'on');
            set(app.Button_2, 'Enable', 'on');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            code = decode(app,app.input_img);

            app.Label_2.Text = (num2str(code));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Barcode_decoderUIFigure = uifigure('Visible', 'off');
            app.Barcode_decoderUIFigure.Name = 'Barcode_decoder';
            app.Button = uibutton(app.Barcode_decoderUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Обзор';
            app.Button_2 = uibutton(app.Barcode_decoderUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Выполнить';
            app.Label = uilabel(app.Barcode_decoderUIFigure);
            app.Label.Text = 'Считанный штрих-код :';
            app.Label_2 = uilabel(app.Barcode_decoderUIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.Barcode_decoderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Barcode_decoderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Barcode_decoderUIFigure)
        end
    end
end